classdef intensity_poisson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        SensorDepthmSliderLabel    matlab.ui.control.Label
        SensorDepthSlider          matlab.ui.control.Slider
        WaterColumnmSliderLabel    matlab.ui.control.Label
        WaterColumnSlider          matlab.ui.control.Slider
        x_maxkmSliderLabel         matlab.ui.control.Label
        xmaxSlider                 matlab.ui.control.Slider
        FigureAttributesPanel      matlab.ui.container.Panel
        ClearFigure                matlab.ui.control.Button
        HoldFigure                 matlab.ui.control.CheckBox
        PlotFigure                 matlab.ui.control.Button
        SimulationTypeButtonGroup  matlab.ui.container.ButtonGroup
        AnalyticalButton           matlab.ui.control.RadioButton
        MonteCarloButton           matlab.ui.control.RadioButton
        BinsLabel                  matlab.ui.control.Label
        bins                       matlab.ui.control.NumericEditField
        PoissonPointsLabel         matlab.ui.control.Label
        PoissonPoints              matlab.ui.control.NumericEditField
        RunButton                  matlab.ui.control.Button
        TransmitIntensityGroup     matlab.ui.container.ButtonGroup
        DeterministicButton        matlab.ui.control.RadioButton
        LogNormalButton            matlab.ui.control.RadioButton
        EditFieldLabel             matlab.ui.control.Label
        mu                         matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        sigma                      matlab.ui.control.NumericEditField
        IndBre1mEditFieldLabel     matlab.ui.control.Label
        It                         matlab.ui.control.NumericEditField
        mEditFieldLabel            matlab.ui.control.Label
        d_edit                     matlab.ui.control.NumericEditField
        mEditField_2Label          matlab.ui.control.Label
        h_edit                     matlab.ui.control.NumericEditField
        kmEditFieldLabel           matlab.ui.control.Label
        x_max                      matlab.ui.control.NumericEditField
        AxisPropButtonGroup        matlab.ui.container.ButtonGroup
        AutoAxis                   matlab.ui.control.RadioButton
        CustomAxis                 matlab.ui.control.RadioButton
        xminEditField_2Label       matlab.ui.control.Label
        xminaxis                   matlab.ui.control.NumericEditField
        xmaxEditField_2Label       matlab.ui.control.Label
        xmaxaxis                   matlab.ui.control.NumericEditField
        xaxisprop                  matlab.ui.container.ButtonGroup
        dBxaxis                    matlab.ui.control.RadioButton
        logxaxis                   matlab.ui.control.RadioButton
        linearxaxis                matlab.ui.control.RadioButton
        ChannelPhysicsPanel        matlab.ui.container.Panel
        Spreading                  matlab.ui.control.CheckBox
        Absorption                 matlab.ui.control.CheckBox
        CoeffdBkmLabel             matlab.ui.control.Label
        Coeff                      matlab.ui.control.NumericEditField
        AbsorptionFilter           matlab.ui.control.CheckBox
        StatisticButtonGroup       matlab.ui.container.ButtonGroup
        IntensityButton            matlab.ui.control.RadioButton
        PressureButton             matlab.ui.control.RadioButton
        StableFitPanel             matlab.ui.container.Panel
        alphaEditFieldLabel        matlab.ui.control.Label
        alpha                      matlab.ui.control.NumericEditField
        betaEditFieldLabel         matlab.ui.control.Label
        beta                       matlab.ui.control.NumericEditField
        FitButton                  matlab.ui.control.Button
        deltaEditField_2Label      matlab.ui.control.Label
        delta                      matlab.ui.control.NumericEditField
        muEditFieldLabel           matlab.ui.control.Label
        muSaS                      matlab.ui.control.NumericEditField
        PlotButton                 matlab.ui.control.Button
        TailPolynomialFitPanel     matlab.ui.container.Panel
        FitTail                    matlab.ui.control.StateButton
        xminEditFieldLabel         matlab.ui.control.Label
        xmin_tailfit               matlab.ui.control.NumericEditField
        xmaxEditFieldLabel         matlab.ui.control.Label
        xmax_tailfit               matlab.ui.control.NumericEditField
        GradientEditFieldLabel     matlab.ui.control.Label
        Gradient                   matlab.ui.control.NumericEditField
        yinterceptEditFieldLabel   matlab.ui.control.Label
        yintercept                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ir      % the received intensity.
        pd      % the stable fit
    end
    
    methods (Access = private)
        
        function b = absorptionFilter(app,N,Fs,distance,depth,rho,S,T)
            %b = absorptionFilter(app,N,Fs)
            d = app.d_edit.Value;
            h = app.h_edit.Value;
            xmax = app.x_max.Value*1000;
            
            r=sqrt((h-d)^2+xmax^2);
            
            %ABSORPTIONFILTER Computes a sound absorption FIR filter for the ocean
            %
            %  b = absorptionFilter(N,Fs,distance,depth,rho,S,T)
            %    N is the order of the filter
            %    Fs is the sampling rate
            %    distance is in meters (optional)
            %    depth is distance in meters (optional)
            %    rho is density in kg/m3 (optional)
            %    S is salinity in parts per thousand (optional)
            %    T is temperature in deg C (optional)
            %    b contains FIR filter coefficients
            %
            % See absorption for default values and computation details.  The function uses
            % absorption to compute the frequency response of the filter and then fir2 to
            % generate the filter.
            %
            % Author: Mandar Chitre
            % Last modified: Aug 18, 2004
            % $Revision: 1.1 $
            
            % optional parameters
            distance = r;
            depth = d;
            rho = [];
            S = [];
            T = [];
            
            % compute filter response
            f = 0:0.1:1;
            for j = 1:length(f)
                freq = f(j)*Fs/2;
                a(j) = absorption(freq,distance,depth,rho,S,T);
            end
            
            % compute filter
            b = fir2(N,f,a);
            
        end
        
        function a = absorption(freq,distance,depth,rho,S,T)
            
            %ABSORPTION Computes sound absorption in the ocean
            %
            %  a = absorption(freq,distance,depth,rho,S,T)
            %    freq is frequency in Hz
            %    distance is in meters, default: 1000
            %    depth is distance in meters, default: 10
            %    rho is density in kg/m3, default: 1023
            %    S is salinity in parts per thousand, default: 35
            %    T is temperature in deg C, default: 27
            %    a is absorption in linear space
            %
            % Reference: Brekhovskikh & Lysanov, Fundamentals of Ocean Acoustics, p9-10.
            %   Based on work by Marsh & Schulkin, JASA 34, p864-865, 1962.
            %   Based on work by Thorp & Browning, J. Sound Vib. 26, p576-578, 1973.
            %
            % Author: Mandar Chitre
            % Last modified: Dec 16, 2003
            % $Revision: 1.3 $
            
            % optional parameters
            if nargin < 2, distance = []; end
            if nargin < 3, depth = []; end
            if nargin < 4, rho = []; end
            if nargin < 5, S = []; end
            if nargin < 6, T = []; end
            
            % constants and defaults
            atm = 1e5/9.8;                    % atmospheric pressure, kg/m3
            if isempty(distance)
                distance = 1000;                % default distance, m
            end
            if isempty(depth)
                depth = 10;                     % nominal depth, m
            end
            if isempty(rho)
                rho = 1023;                     % density of sea water, kg/m3
            end
            if isempty(S)
                S = 35;                         % salinity of sea water, parts per thousand
            end
            if isempty(T)
                T = 27;                         % water temperature, deg C
            end
            
            % derived quantities
            f = freq/1000;                    % frequency, kHz
            P = (rho*depth+atm)*1e-4;         % hydrostatic pressure, kg/cm3
            fT = 21.9*10^(6-1520/(T+273));    % relaxation frequency, kHz
            
            if f > 3
                % Marsh & Schulkin
                A = 2.34e-6;
                B = 3.38e-6;
                a = 8.68*(S*A*fT*f*f/(fT*fT+f*f)+B*f*f/fT)*(1-6.54e-4*P);
            else
                % Thorp
                a = (0.11*f*f/(1+f*f)+44*f*f/(4100+f*f))*1e-3;
            end
            
            % scale by distance and convert to linear scale
            a = 10^(-a*distance/20);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.crow=1;
            
        end

        % Value changed function: Absorption
        function AbsorptionValueChanged(app, event)
            if app.Absorption.Value
                app.AbsorptionFilter.Value=false
                app.Coeff.Enable='on';
            else
                app.Coeff.Enable='off';
            end
            
        end

        % Selection changed function: AxisPropButtonGroup
        function AxisPropButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisPropButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Auto'
                    axis(app.UIAxes,'auto')
                    set(app.xminaxis,'enable','off')
                    set(app.xmaxaxis,'enable','off')
                    %   set(app.yminaxis,'enable','off')
                    %   set(app.ymaxaxis,'enable','off')
                case 'Custom'
                    if app.xminaxis.Value < app.xmaxaxis.Value
                        set(app.UIAxes,'xlim',[app.xminaxis.Value, app.xmaxaxis.Value]);
                        %       set(app.UIAxes,'ylim',[app.yminaxis.Value, app.ymaxaxis.Value]);
                    end
                    set(app.xminaxis,'enable','on')
                    set(app.xmaxaxis,'enable','on')
                    %    set(app.yminaxis,'enable','on')
                    %    set(app.ymaxaxis,'enable','on')
            end
            
        end

        % Button pushed function: ClearFigure
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes)
            hold(app.UIAxes,'off')
            app.HoldFigure.Value=0;
            set(app.dBxaxis,'enable','on')
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            if ~isempty(app.ir)
                p=sqrt(app.ir).*(2*randi(2,1,length(app.ir))-3);
                app.pd = fitdist(p(1:min(length(p),10^5)).','Stable');
                app.alpha.Value=round(app.pd.alpha,2);
                app.beta.Value=round(app.pd.beta,2);
                app.delta.Value=round(app.pd.gam,2);
                app.muSaS.Value=round(app.pd.delta,2);
                
            end
            
        end

        % Value changed function: HoldFigure
        function HoldFigureValueChanged(app, event)
            if app.HoldFigure.Value
                hold(app.UIAxes,'on')
                %set(app.xaxisprop,'enable','off')
                app.CustomAxis.Value=true;
            else
                hold(app.UIAxes,'off')
                set(app.dBxaxis,'enable','on')
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if ~isempty(app.pd)
                
                if app.CustomAxis.Value
                    Xlim=get(app.UIAxes,'Xlim');
                    if app.dBxaxis.Value
                        i_max=10^(Xlim(2)/10);
                        i_min=10^(Xlim(1)/10);
                    else
                        i_max=Xlim(2);
                        if Xlim(1)<=10^-50 && app.logxaxis.Value
                            Xlim(1)=10^-50;
                        end
                        i_min=Xlim(1);
                    end
                else
                    i_max=(20*app.pd.gam)^2;
                    i_min=10^-4;
                    if app.PressureButton.Value
                        i_min=sqrt(i_min);
                        i_max=sqrt(i_max);
                    end
                end
                
                if app.dBxaxis.Value
                    i_dB=10*log10(i_min):(10*log10(i_max)-10*log10(i_min))/500:10*log10(i_max);
                    i=10.^(i_dB/10);
                elseif app.linearxaxis.Value
                    i=i_min:(i_max-i_min)/500:i_max;
                else
                    i_log=log10(i_min):(log10(i_max)-log10(i_min))/500:log10(i_max);
                    i=10.^(i_log);
                end
                
                stab=makedist('Stable','alpha',app.pd.alpha,'beta',app.pd.beta,'gam',app.pd.gam,'delta',app.pd.delta);
                %x_test=random(stab,100000,1);
                if app.IntensityButton.Value
                    f=pdf(stab,sqrt(i))./sqrt(i);
                    %histogram(app.UIAxes,x_test.^2,i,'normalization','pdf');
                else
                    f=2*pdf(stab,i);
                    %histogram(app.UIAxes,abs(x_test),i,'normalization','pdf');
                end
                %hold(app.UIAxes,'on')
                if app.dBxaxis.Value
                    %hold(app.UIAxes,'off')
                    g_diff_i= 10*log10(exp(1))./i; % g(i)=10log10(i)
                    %histogram(app.UIAxes,10*log10(abs(x_test).^2),10*log10(i),'normalization','pdf');
                    %hold(app.UIAxes,'on')
                    semilogy(app.UIAxes,10*log10(i),f./g_diff_i,'linewidth',2);
                    %hold(app.UIAxes,'off')
                    set(app.UIAxes,'xlim',[10*log10(i(1)),10*log10(i(end))])
                else
                    semilogy(app.UIAxes,i,f,'linewidth',2)
                    %hold(app.UIAxes,'off')
                    set(app.UIAxes,'xlim',[i(1),i(end)])
                end
                
                
                if app.logxaxis.Value
                    set(app.UIAxes,'xscale','log')
                else
                    set(app.UIAxes,'xscale','linear')
                end
                
                if app.IntensityButton.Value && app.dBxaxis.Value
                    xlabel(app.UIAxes,'observed intensity (dB)')
                elseif app.IntensityButton.Value && ~app.dBxaxis.Value
                    xlabel(app.UIAxes,'observed intensity')
                elseif ~app.IntensityButton.Value && app.dBxaxis.Value
                    xlabel(app.UIAxes,'observed pressure (dB)')
                elseif ~app.IntensityButton.Value && ~app.dBxaxis.Value
                    xlabel(app.UIAxes,'observed pressure')
                else
                    xlabel(app.UIAxes,'errorr!!!')
                end
                
                if app.dBxaxis.Value
                    app.xminaxis.Limits=[-Inf Inf];
                    app.xmaxaxis.Limits=[-Inf Inf];
                    app.xminaxis.ValueDisplayFormat='%3.1f';
                    app.xmaxaxis.ValueDisplayFormat='%3.1f';
                else
                    app.xminaxis.Limits=[0 Inf];
                    app.xmaxaxis.Limits=[0 Inf];
                    app.xminaxis.ValueDisplayFormat='%1.2g';
                    app.xmaxaxis.ValueDisplayFormat='%1.2g';
                end
                Xlim=get(app.UIAxes,'Xlim');
                app.xminaxis.Value=Xlim(1);
                app.xmaxaxis.Value=Xlim(2);
            end
        end

        % Button pushed function: PlotFigure
        function PlotFigureButtonPushed(app, event)
            %plot(app.UIAxes,randn(100,1))
            
            d = app.d_edit.Value;
            h = app.h_edit.Value;
            xmax = app.x_max.Value*1000;
            
            
            simtype = app.SimulationTypeButtonGroup.SelectedObject;
            It_type = app.TransmitIntensityGroup.SelectedObject;
            
            if app.CustomAxis.Value
                Xlim=get(app.UIAxes,'Xlim');
                if app.dBxaxis.Value
                    i_max=10^(Xlim(2)/10);
                    i_min=10^(Xlim(1)/10);
                else
                    i_max=Xlim(2);
                    if Xlim(1)<=10^-50 && app.logxaxis.Value
                        Xlim(1)=10^-50;
                    end
                    i_min=Xlim(1);
                end
            else
                Xlim=0;
            end
            c1=xmax^2+(h-d)^2;
            c2=(h-d)^2;
            
            
            switch simtype.Text
                case 'Analytical'
                    
                    switch It_type.Text
                        case 'Deterministic'
                            it=app.It.Value;
                            it_lin=10^(it/10);
                            if isequal(Xlim,0)
                                i_max=it_lin/c2*1.1;
                                i_min=it_lin/c1*0.9;
                                if app.PressureButton.Value
                                    i_min=sqrt(i_min);
                                    i_max=sqrt(i_max);
                                end
                            end
                            if app.dBxaxis.Value
                                i_dB=10*log10(i_min):(10*log10(i_max)-10*log10(i_min))/500:10*log10(i_max);
                                i=10.^(i_dB/10);
                            elseif app.linearxaxis.Value
                                i=i_min:(i_max-i_min)/500:i_max;
                            else
                                i_log=log10(i_min):(log10(i_max)-log10(i_min))/500:log10(i_max);
                                i=10.^(i_log);
                            end
                            
                            f=zeros(1,length(i));
                            if app.IntensityButton.Value
                                i_nz_ind=and(i<=it_lin/c2, i>=it_lin/c1);
                                f(i_nz_ind)=(it_lin)./(((i(i_nz_ind)).^2)*xmax^2);
                            else
                                p_nz_ind=and(i.^2<=it_lin/c2, i.^2>=it_lin/c1);
                                f(p_nz_ind)=2*i(p_nz_ind).*(it_lin)./(((i(p_nz_ind)).^4)*xmax^2);
                            end
                            
                        case 'Log Normal'
                            mu_pdf=app.mu.Value/(10*log10(exp(1)));
                            sigma_pdf=app.sigma.Value/(10*log10(exp(1)));
                            if isequal(Xlim,0)
                                i_max=(10^((app.mu.Value+3*app.sigma.Value)/10))/c2;
                                i_min=(10^((app.mu.Value-3*app.sigma.Value)/10))/c1;
                                if app.PressureButton.Value
                                    i_min=sqrt(i_min);
                                    i_max=sqrt(i_max);
                                end
                            end
                            if app.dBxaxis.Value
                                i_dB=10*log10(i_min):(10*log10(i_max)-10*log10(i_min))/500:10*log10(i_max);
                                i=10.^(i_dB/10);
                            elseif app.linearxaxis.Value
                                i=i_min:(i_max-i_min)/500:i_max;
                            else
                                i_log=log10(i_min):(log10(i_max)-log10(i_min))/500:log10(i_max);
                                i=10.^(i_log);
                            end
                            if app.IntensityButton.Value
                                f=pdf('lognormal',i*c1,mu_pdf,sigma_pdf)*(c1^2/(xmax^2))+pdf('lognormal',i*c2,mu_pdf,sigma_pdf)*c2*(1-(c1/xmax^2))...
                                    +exp(mu_pdf+(sigma_pdf^2)/2)*sqrt(2/pi)./(2*(i.^2)*sigma_pdf*(xmax^2)) .* ( exp(-((mu_pdf+sigma_pdf^2-log(c2*i)).^2)./(2*sigma_pdf^2))...
                                    -exp(-((mu_pdf+sigma_pdf^2-log(c1*i)).^2)./(2*sigma_pdf^2)))...
                                    +exp(mu_pdf+(sigma_pdf^2)/2)./(2*(i.^2)*(xmax^2)).*(erf((mu_pdf+sigma_pdf^2-log(i*c2))/(sqrt(2)*sigma_pdf))...
                                    -erf((mu_pdf+sigma_pdf^2-log(i*c1))/(sqrt(2)*sigma_pdf)));
                            else
                                f=2*i.*(pdf('lognormal',(i.^2)*c1,mu_pdf,sigma_pdf)*(c1^2/(xmax^2))+pdf('lognormal',(i.^2)*c2,mu_pdf,sigma_pdf)*c2*(1-(c1/xmax^2))...
                                    +exp(mu_pdf+(sigma_pdf^2)/2)*sqrt(2/pi)./(2*(i.^4)*sigma_pdf*(xmax^2)) .* ( exp(-((mu_pdf+sigma_pdf^2-log(c2*(i.^2))).^2)./(2*sigma_pdf^2))...
                                    -exp(-((mu_pdf+sigma_pdf^2-log(c1*(i.^2))).^2)./(2*sigma_pdf^2)))...
                                    +exp(mu_pdf+(sigma_pdf^2)/2)./(2*(i.^4)*(xmax^2)).*(erf((mu_pdf+sigma_pdf^2-log((i.^2)*c2))/(sqrt(2)*sigma_pdf))...
                                    -erf((mu_pdf+sigma_pdf^2-log((i.^2)*c1))/(sqrt(2)*sigma_pdf))));
                            end
                            
                    end
                    
                    if app.dBxaxis.Value
                        g_diff_i= 10*log10(exp(1))./i; % g(i)=10log10(i)
                        semilogy(app.UIAxes,10*log10(i),f./g_diff_i,'linewidth',2);
                        set(app.UIAxes,'xlim',[10*log10(i(1)),10*log10(i(end))])
                    else
                        semilogy(app.UIAxes,i,f,'linewidth',2)
                        set(app.UIAxes,'xlim',[i(1),i(end)])
                    end
                    
                    
                case 'Monte Carlo'
                    nbins=app.bins.Value;
                    if app.PressureButton.Value
                        app.ir=sqrt(app.ir);
                    end
                    switch It_type.Text
                        case 'Deterministic'
                            if isequal(Xlim,0)
                                it=app.It.Value;
                                it_lin=10^(it/10);
                                i_max=it_lin/c2;
                                i_min=it_lin/c1;
                                if app.PressureButton.Value
                                    i_min=sqrt(i_min);
                                    i_max=sqrt(i_max);
                                end
                            end
                            if app.dBxaxis.Value
                                i=10*log10(i_min):(10*log10(i_max)-10*log10(i_min))/nbins:10*log10(i_max);
                                i=10.^(i/10);
                            elseif app.linearxaxis.Value
                                i=i_min:(i_max-i_min)/nbins:i_max;
                            else
                                i_log=log10(i_min):(log10(i_max)-log10(i_min))/nbins:log10(i_max);
                                i=10.^(i_log);
                            end
                        case 'Log Normal'
                            if isequal(Xlim,0)
                                ir_temp=quantile(app.ir(1:min(10^5,length(app.ir))),[10^-6,1-10^-6]);
                                i_min=ir_temp(1);
                                i_max=ir_temp(2);
                                %if app.PressureButton.Value
                                %    i_min=sqrt(i_min);
                                %    i_max=sqrt(i_max);
                                %end
                                %i_max=(10^((app.mu.Value+3*app.sigma.Value)/10))/c2;
                                %i_min=(10^((app.mu.Value-3*app.sigma.Value)/10))/c1;
                            end
                            if app.dBxaxis.Value
                                i=10*log10(i_min):(10*log10(i_max)-10*log10(i_min))/nbins:10*log10(i_max);
                                i=10.^(i/10);
                            elseif app.linearxaxis.Value
                                i=i_min:(i_max-i_min)/nbins:i_max;
                            else
                                i_log=log10(i_min):(log10(i_max)-log10(i_min))/nbins:log10(i_max);
                                i=10.^(i_log);
                            end
                            
                    end
                    if app.dBxaxis.Value
                        histogram(app.UIAxes,10*log10(app.ir),10*log10(i),'normalization','pdf')
                        set(app.UIAxes,'yscale','log')
                        set(app.UIAxes,'xlim',[10*log10(i(1)),10*log10(i(end))])
                    else
                        histogram(app.UIAxes,app.ir,i,'normalization','pdf')
                        set(app.UIAxes,'yscale','log')
                        set(app.UIAxes,'xlim',[i(1),i(end)])
                    end
                    if app.PressureButton.Value
                        app.ir=(app.ir).^2;
                    end
                    
            end
            if app.IntensityButton.Value && app.dBxaxis.Value
                xlabel(app.UIAxes,'observed intensity (dB)')
            elseif app.IntensityButton.Value && ~app.dBxaxis.Value
                xlabel(app.UIAxes,'observed intensity')
            elseif ~app.IntensityButton.Value && app.dBxaxis.Value
                xlabel(app.UIAxes,'observed pressure (dB)')
            elseif ~app.IntensityButton.Value && ~app.dBxaxis.Value
                xlabel(app.UIAxes,'observed pressure')
            else
                xlabel(app.UIAxes,'errorr!!!')
            end
            
            if app.dBxaxis.Value
                app.xminaxis.Limits=[-Inf Inf];
                app.xmaxaxis.Limits=[-Inf Inf];
                app.xminaxis.ValueDisplayFormat='%3.1f';
                app.xmaxaxis.ValueDisplayFormat='%3.1f';
            else
                app.xminaxis.Limits=[0 Inf];
                app.xmaxaxis.Limits=[0 Inf];
                app.xminaxis.ValueDisplayFormat='%1.2g';
                app.xmaxaxis.ValueDisplayFormat='%1.2g';
            end
            
            if app.logxaxis.Value
                set(app.UIAxes,'xscale','log')
            else
                set(app.UIAxes,'xscale','linear')
            end
            
            
            Xlim=get(app.UIAxes,'Xlim');
            app.xminaxis.Value=Xlim(1);
            app.xmaxaxis.Value=Xlim(2);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            d = app.d_edit.Value;
            h = app.h_edit.Value;
            xmax = app.x_max.Value*1000;
            N=app.PoissonPoints.Value;
            
            selectedButton = app.TransmitIntensityGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log Normal')
                it_dB=app.mu.Value+randn(1,N)*app.sigma.Value;
            else
                it_dB=repmat(app.It.Value,1,N);
            end
            %it=10.^(it/10);
            
            x=sqrt(rand(1,N))*xmax;
            r=sqrt(x.^2+(d-h).^2);
            
            if app.Absorption.Value
                %app.ir=it.*(r.^(-2));
                ir_dB = it_dB - 20*log10(r) - app.Coeff.Value*(r/1000); % in dB => app.Coeff.Value is in dB/km
                % linear: Ir = It * (r^-2) * 10^(- alpha*r/(1000*10))
            else
                ir_dB = it_dB - 20*log10(r);
            end
            app.ir=10.^(ir_dB/10);
            set(app.PlotFigure,'enable','on')
            PlotFigureButtonPushed(app, event)
            
        end

        % Value changed function: SensorDepthSlider
        function SensorDepthSliderValueChanged(app, event)
            app.SensorDepthSlider.Value= round(app.SensorDepthSlider.Value,0);
            d = app.SensorDepthSlider.Value;
            h=app.WaterColumnSlider.Value;
            if d>h
                app.SensorDepthSlider.Value=h;
            end
            app.d_edit.Value=app.SensorDepthSlider.Value;
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Monte Carlo')
                set(app.Absorption,'enable','on')
                set(app.Coeff,'enable','on')
                set(app.bins,'enable','on')
                set(app.PoissonPoints,'enable','on')
                set(app.RunButton,'enable','on')
                if isempty(app.ir)
                    set(app.PlotFigure,'enable','off')
                else
                    set(app.PlotFigure,'enable','on')
                end
                app.PlotFigure.Text='Plot : Monte Carlo';
            else
                set(app.Absorption,'enable','off')
                set(app.Coeff,'enable','off')
                set(app.bins,'enable','off')
                set(app.PoissonPoints,'enable','off')
                set(app.RunButton,'enable','off')
                set(app.PlotFigure,'enable','on')
                app.PlotFigure.Text='Plot : Analytic';
            end
        end

        % Selection changed function: TransmitIntensityGroup
        function TransmitIntensityGroupSelectionChanged(app, event)
            selectedButton = app.TransmitIntensityGroup.SelectedObject;
            switch selectedButton.Text
                case 'Deterministic'
                    set(app.mu,'enable','off')
                    set(app.sigma,'enable','off')
                    set(app.It,'enable','on')
                case 'Log Normal'
                    set(app.mu,'enable','on')
                    set(app.sigma,'enable','on')
                    set(app.It,'enable','off')
            end
        end

        % Value changed function: WaterColumnSlider
        function WaterColumnSliderValueChanged(app, event)
            app.WaterColumnSlider.Value=round(app.WaterColumnSlider.Value,0);
            h = app.WaterColumnSlider.Value;
            app.h_edit.Value = h;
            d = app.SensorDepthSlider.Value;
            if d>h
                app.SensorDepthSlider.Value=h;
                app.d_edit.Value=h;
            end
            
        end

        % Value changed function: d_edit
        function d_editEditFieldValueChanged(app, event)
            app.d_edit.Value=round(app.d_edit.Value,0);
            d = app.d_edit.Value;
            h = app.WaterColumnSlider.Value;
            if d>h
                app.d_edit.Value=h;
            end
            app.SensorDepthSlider.Value=app.d_edit.Value;
        end

        % Value changed function: h_edit
        function h_editEditFieldValueChanged(app, event)
            app.h_edit.Value=round(app.h_edit.Value,0);
            app.WaterColumnSlider.Value=app.h_edit.Value;
            d = app.SensorDepthSlider.Value;
            h = app.WaterColumnSlider.Value;
            if d>h
                app.SensorDepthSlider.Value=h;
                app.d_edit.Value=h;
            end
        end

        % Callback function
        function holdxaxisCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: x_max
        function x_maxValueChanged(app, event)
            app.x_max.Value = round(app.x_max.Value,1);
            app.xmaxSlider.Value = app.x_max.Value;
            
        end

        % Value changed function: xmaxSlider
        function xmaxSliderValueChanged(app, event)
            app.xmaxSlider.Value = round(app.xmaxSlider.Value,1);
            app.x_max.Value = app.xmaxSlider.Value;
            
        end

        % Value changed function: xmaxaxis, xminaxis
        function xminaxisValueChanged(app, event)
            if (app.xminaxis.Value < app.xmaxaxis.Value)
                set(app.UIAxes,'xlim',[app.xminaxis.Value, app.xmaxaxis.Value]);
                %PlotFigureButtonPushed(app,event)
                % set(app.UIAxes,'ylim',[app.yminaxis.Value, app.ymaxaxis.Value]);
            end
        end

        % Selection changed function: xaxisprop
        function xaxispropSelectionChanged(app, event)
            app.AutoAxis.Value=true;
            app.HoldFigure.Value=false;
            HoldFigureValueChanged(app, event)
            if app.dBxaxis.Value && ~isempty(app.ir)
                set(app.FitTail,'enable','on')
                set(app.xmin_tailfit,'enable','on')
                set(app.xmax_tailfit,'enable','on')
            else
                set(app.FitTail,'enable','off')
                set(app.xmin_tailfit,'enable','off')
                set(app.xmax_tailfit,'enable','off')
            end                
        end

        % Value changed function: AbsorptionFilter
        function AbsorptionFilterValueChanged(app, event)
            if app.AbsorptionFilter.Value
                app.Absorption.Value=false;
                app.Coeff.Enable='off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'observed intensity')
            ylabel(app.UIAxes, 'PDF')
            app.SensorDepthmSliderLabel = uilabel(app.UIFigure);
            app.SensorDepthmSliderLabel.Text = 'Sensor Depth (m)';
            app.SensorDepthSlider = uislider(app.UIFigure);
            app.SensorDepthSlider.Limits = [0 50];
            app.SensorDepthSlider.ValueChangedFcn = createCallbackFcn(app, @SensorDepthSliderValueChanged, true);
            app.SensorDepthSlider.Value = 5;
            app.WaterColumnmSliderLabel = uilabel(app.UIFigure);
            app.WaterColumnmSliderLabel.Text = 'Water Column (m)';
            app.WaterColumnSlider = uislider(app.UIFigure);
            app.WaterColumnSlider.Limits = [0 50];
            app.WaterColumnSlider.ValueChangedFcn = createCallbackFcn(app, @WaterColumnSliderValueChanged, true);
            app.WaterColumnSlider.Value = 10;
            app.x_maxkmSliderLabel = uilabel(app.UIFigure);
            app.x_maxkmSliderLabel.Text = 'x_max (km)';
            app.xmaxSlider = uislider(app.UIFigure);
            app.xmaxSlider.Limits = [0 50];
            app.xmaxSlider.ValueChangedFcn = createCallbackFcn(app, @xmaxSliderValueChanged, true);
            app.xmaxSlider.Value = 5;
            app.FigureAttributesPanel = uipanel(app.UIFigure);
            app.FigureAttributesPanel.Title = 'Figure Attributes';
            app.ClearFigure = uibutton(app.FigureAttributesPanel, 'push');
            app.ClearFigure.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigure.Text = 'Clear Figure';
            app.HoldFigure = uicheckbox(app.FigureAttributesPanel);
            app.HoldFigure.ValueChangedFcn = createCallbackFcn(app, @HoldFigureValueChanged, true);
            app.HoldFigure.Text = ' Hold Figure';
            app.PlotFigure = uibutton(app.FigureAttributesPanel, 'push');
            app.PlotFigure.ButtonPushedFcn = createCallbackFcn(app, @PlotFigureButtonPushed, true);
            app.PlotFigure.Enable = 'off';
            app.PlotFigure.Text = 'Plot : Monte Carlo';
            app.SimulationTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeButtonGroupSelectionChanged, true);
            app.SimulationTypeButtonGroup.Title = 'Simulation Type';
            app.AnalyticalButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.AnalyticalButton.Text = 'Analytical';
            app.MonteCarloButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.MonteCarloButton.Value = true;
            app.BinsLabel = uilabel(app.SimulationTypeButtonGroup);
            app.BinsLabel.Text = 'Bins';
            app.bins = uieditfield(app.SimulationTypeButtonGroup, 'numeric');
            app.bins.Limits = [10 2000];
            app.bins.Value = 100;
            app.PoissonPointsLabel = uilabel(app.SimulationTypeButtonGroup);
            app.PoissonPointsLabel.Text = 'Poisson Points';
            app.PoissonPoints = uieditfield(app.SimulationTypeButtonGroup, 'numeric');
            app.PoissonPoints.Limits = [0 100000000];
            app.PoissonPoints.ValueDisplayFormat = '%1.2g';
            app.PoissonPoints.Value = 10000000;
            app.RunButton = uibutton(app.SimulationTypeButtonGroup, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TransmitIntensityGroup = uibuttongroup(app.UIFigure);
            app.TransmitIntensityGroup.SelectionChangedFcn = createCallbackFcn(app, @TransmitIntensityGroupSelectionChanged, true);
            app.TransmitIntensityGroup.Title = 'Transmit Intensity';
            app.DeterministicButton = uiradiobutton(app.TransmitIntensityGroup);
            app.DeterministicButton.Text = 'Deterministic';
            app.LogNormalButton = uiradiobutton(app.TransmitIntensityGroup);
            app.LogNormalButton.Text = 'Log Normal';
            app.LogNormalButton.Value = true;
            app.EditFieldLabel = uilabel(app.TransmitIntensityGroup);
            app.EditFieldLabel.Text = 'μ';
            app.mu = uieditfield(app.TransmitIntensityGroup, 'numeric');
            app.mu.Value = 100;
            app.Label = uilabel(app.TransmitIntensityGroup);
            app.Label.Text = 'σ';
            app.sigma = uieditfield(app.TransmitIntensityGroup, 'numeric');
            app.sigma.Value = 5;
            app.IndBre1mEditFieldLabel = uilabel(app.TransmitIntensityGroup);
            app.IndBre1mEditFieldLabel.Enable = 'off';
            app.IndBre1mEditFieldLabel.Text = 'In dB re @ 1m';
            app.It = uieditfield(app.TransmitIntensityGroup, 'numeric');
            app.It.Enable = 'off';
            app.It.Value = 100;
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.d_edit = uieditfield(app.UIFigure, 'numeric');
            app.d_edit.ValueChangedFcn = createCallbackFcn(app, @d_editEditFieldValueChanged, true);
            app.d_edit.Limits = [0 50];
            app.d_edit.Value = 5;
            app.mEditField_2Label = uilabel(app.UIFigure);
            app.mEditField_2Label.Text = 'm';
            app.h_edit = uieditfield(app.UIFigure, 'numeric');
            app.h_edit.ValueChangedFcn = createCallbackFcn(app, @h_editEditFieldValueChanged, true);
            app.h_edit.Limits = [0 50];
            app.h_edit.Value = 10;
            app.kmEditFieldLabel = uilabel(app.UIFigure);
            app.kmEditFieldLabel.Text = 'km';
            app.x_max = uieditfield(app.UIFigure, 'numeric');
            app.x_max.ValueChangedFcn = createCallbackFcn(app, @x_maxValueChanged, true);
            app.x_max.Limits = [0 50];
            app.x_max.Value = 5;
            app.AxisPropButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisPropButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisPropButtonGroupSelectionChanged, true);
            app.AxisPropButtonGroup.Title = 'Axis Prop.';
            app.AutoAxis = uiradiobutton(app.AxisPropButtonGroup);
            app.AutoAxis.Text = 'Auto';
            app.AutoAxis.Value = true;
            app.CustomAxis = uiradiobutton(app.AxisPropButtonGroup);
            app.CustomAxis.Text = 'Custom';
            app.xminEditField_2Label = uilabel(app.AxisPropButtonGroup);
            app.xminEditField_2Label.Enable = 'off';
            app.xminEditField_2Label.Text = 'xmin';
            app.xminaxis = uieditfield(app.AxisPropButtonGroup, 'numeric');
            app.xminaxis.ValueChangedFcn = createCallbackFcn(app, @xminaxisValueChanged, true);
            app.xminaxis.Limits = [0 Inf];
            app.xminaxis.Enable = 'off';
            app.xmaxEditField_2Label = uilabel(app.AxisPropButtonGroup);
            app.xmaxEditField_2Label.Enable = 'off';
            app.xmaxEditField_2Label.Text = 'xmax';
            app.xmaxaxis = uieditfield(app.AxisPropButtonGroup, 'numeric');
            app.xmaxaxis.ValueChangedFcn = createCallbackFcn(app, @xminaxisValueChanged, true);
            app.xmaxaxis.Limits = [0 Inf];
            app.xmaxaxis.Enable = 'off';
            app.xmaxaxis.Value = 1;
            app.xaxisprop = uibuttongroup(app.AxisPropButtonGroup);
            app.xaxisprop.SelectionChangedFcn = createCallbackFcn(app, @xaxispropSelectionChanged, true);
            app.xaxisprop.Title = 'x-axis';
            app.dBxaxis = uiradiobutton(app.xaxisprop);
            app.dBxaxis.Text = 'Decibels';
            app.logxaxis = uiradiobutton(app.xaxisprop);
            app.logxaxis.Text = 'Log';
            app.linearxaxis = uiradiobutton(app.xaxisprop);
            app.linearxaxis.Text = 'Linear';
            app.linearxaxis.Value = true;
            app.ChannelPhysicsPanel = uipanel(app.UIFigure);
            app.ChannelPhysicsPanel.Title = 'Channel Physics ';
            app.Spreading = uicheckbox(app.ChannelPhysicsPanel);
            app.Spreading.Enable = 'off';
            app.Spreading.Text = 'Spherical Spreading';
            app.Spreading.Value = true;
            app.Absorption = uicheckbox(app.ChannelPhysicsPanel);
            app.Absorption.ValueChangedFcn = createCallbackFcn(app, @AbsorptionValueChanged, true);
            app.Absorption.Text = 'Absorption';
            app.CoeffdBkmLabel = uilabel(app.ChannelPhysicsPanel);
            app.CoeffdBkmLabel.Text = 'Coeff (dB/km)';
            app.Coeff = uieditfield(app.ChannelPhysicsPanel, 'numeric');
            app.Coeff.Enable = 'off';
            app.Coeff.Value = 10;
            app.AbsorptionFilter = uicheckbox(app.ChannelPhysicsPanel);
            app.AbsorptionFilter.ValueChangedFcn = createCallbackFcn(app, @AbsorptionFilterValueChanged, true);
            app.AbsorptionFilter.Text = 'Absorption Filter';
            app.StatisticButtonGroup = uibuttongroup(app.UIFigure);
            app.StatisticButtonGroup.Title = 'Statistic';
            app.IntensityButton = uiradiobutton(app.StatisticButtonGroup);
            app.IntensityButton.Text = 'Intensity';
            app.IntensityButton.Value = true;
            app.PressureButton = uiradiobutton(app.StatisticButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.StableFitPanel = uipanel(app.UIFigure);
            app.StableFitPanel.Title = 'Stable Fit';
            app.alphaEditFieldLabel = uilabel(app.StableFitPanel);
            app.alphaEditFieldLabel.Enable = 'off';
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.StableFitPanel, 'numeric');
            app.alpha.ValueDisplayFormat = '%2.2f';
            app.alpha.Enable = 'off';
            app.betaEditFieldLabel = uilabel(app.StableFitPanel);
            app.betaEditFieldLabel.Enable = 'off';
            app.betaEditFieldLabel.Text = 'beta';
            app.beta = uieditfield(app.StableFitPanel, 'numeric');
            app.beta.ValueDisplayFormat = '%2.2f';
            app.beta.Enable = 'off';
            app.FitButton = uibutton(app.StableFitPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.deltaEditField_2Label = uilabel(app.StableFitPanel);
            app.deltaEditField_2Label.Enable = 'off';
            app.deltaEditField_2Label.Text = 'delta';
            app.delta = uieditfield(app.StableFitPanel, 'numeric');
            app.delta.ValueDisplayFormat = '%2.2f';
            app.delta.Enable = 'off';
            app.muEditFieldLabel = uilabel(app.StableFitPanel);
            app.muEditFieldLabel.Enable = 'off';
            app.muEditFieldLabel.Text = 'mu';
            app.muSaS = uieditfield(app.StableFitPanel, 'numeric');
            app.muSaS.ValueDisplayFormat = '%2.2f';
            app.muSaS.Enable = 'off';
            app.PlotButton = uibutton(app.StableFitPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TailPolynomialFitPanel = uipanel(app.UIFigure);
            app.TailPolynomialFitPanel.Title = 'Tail Polynomial Fit';
            app.FitTail = uibutton(app.TailPolynomialFitPanel, 'state');
            app.FitTail.Enable = 'off';
            app.FitTail.Text = 'Fit Tail (dB)';
            app.xminEditFieldLabel = uilabel(app.TailPolynomialFitPanel);
            app.xminEditFieldLabel.Enable = 'off';
            app.xminEditFieldLabel.Text = 'xmin';
            app.xmin_tailfit = uieditfield(app.TailPolynomialFitPanel, 'numeric');
            app.xmin_tailfit.Enable = 'off';
            app.xmaxEditFieldLabel = uilabel(app.TailPolynomialFitPanel);
            app.xmaxEditFieldLabel.Enable = 'off';
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmax_tailfit = uieditfield(app.TailPolynomialFitPanel, 'numeric');
            app.xmax_tailfit.Enable = 'off';
            app.GradientEditFieldLabel = uilabel(app.TailPolynomialFitPanel);
            app.GradientEditFieldLabel.Enable = 'off';
            app.GradientEditFieldLabel.Text = 'Gradient';
            app.Gradient = uieditfield(app.TailPolynomialFitPanel, 'numeric');
            app.Gradient.Enable = 'off';
            app.yinterceptEditFieldLabel = uilabel(app.TailPolynomialFitPanel);
            app.yinterceptEditFieldLabel.Enable = 'off';
            app.yinterceptEditFieldLabel.Text = 'y-intercept';
            app.yintercept = uieditfield(app.TailPolynomialFitPanel, 'numeric');
            app.yintercept.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = intensity_poisson

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end